classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TextDetectionAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        PrewittEdgeDetectionButton   matlab.ui.control.RadioButton
        OtsusMethodButton            matlab.ui.control.RadioButton
        ColorSelectButton            matlab.ui.control.RadioButton
        UserGeneratedMaskButton      matlab.ui.control.RadioButton
        RemoveTextButton             matlab.ui.control.Button
        ImageFilenameEditFieldLabel  matlab.ui.control.Label
        ImageFilenameEditField       matlab.ui.control.EditField
        RLabel                       matlab.ui.control.Label
        RSpinner                     matlab.ui.control.Spinner
        GLabel                       matlab.ui.control.Label
        GSpinner                     matlab.ui.control.Spinner
        BLabel                       matlab.ui.control.Label
        BSpinner                     matlab.ui.control.Spinner
        DilationSpinnerLabel         matlab.ui.control.Label
        DilationSpinner              matlab.ui.control.Spinner
        ECE590TextDetectionandInpaintingLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        filename = ''; % Description
        detection_method = 'Prewitt Edge Detection';
        dilation = 3;
        R = 255;
        G = 123;
        B = 0;
    end


    methods (Access = private)

        % Value changed function: ImageFilenameEditField
        function ImageFilenameEditFieldValueChanged(app, event)
            value = app.ImageFilenameEditField.Value;
            app.filename = value;
        end

        % Selection changed function: 
        % TextDetectionAlgorithmButtonGroup
        function TextDetectionAlgorithmButtonGroupSelectionChanged(app, event)
            selectedButton = app.TextDetectionAlgorithmButtonGroup.SelectedObject;
            app.detection_method = selectedButton;
        end

        % Button pushed function: RemoveTextButton
        function RemoveTextButtonPushed(app, event)
            app.detection_method.Text
            if (strcmp(app.detection_method.Text, ['Otsu''','s Method']))
                inpaint_text(app.filename, 'otsu', app.dilation, app.R, app.G, app.B);
            elseif (strcmp(app.detection_method.Text,'Prewitt Edge Detection'))
                inpaint_text(app.filename, 'edge', app.dilation, app.R, app.G, app.B);
            elseif (strcmp(app.detection_method.Text,'Color-Select'))
                inpaint_text(app.filename, 'color-select', app.dilation, app.R, app.G, app.B);
            elseif (strcmp(app.detection_method.Text,'User Generated Mask'))
                inpaint_text(app.filename, 'user-generated', app.dilation, app.R, app.G, app.B);
            end
            %%TODO add User Generated Mask
            
        end

        % Value changed function: DilationSpinner
        function DilationSpinnerValueChanged(app, event)
            value = app.DilationSpinner.Value;
            app.dilation = value;
        end

        % Value changed function: RSpinner
        function RSpinnerValueChanged(app, event)
            value = app.RSpinner.Value;
            app.R = value;
        end

        % Value changed function: GSpinner
        function GSpinnerValueChanged(app, event)
            value = app.GSpinner.Value;
            app.G = value;
        end

        % Value changed function: BSpinner
        function BSpinnerValueChanged(app, event)
            value = app.BSpinner.Value;
            app.B = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TextDetectionAlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.TextDetectionAlgorithmButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TextDetectionAlgorithmButtonGroupSelectionChanged, true);
            app.TextDetectionAlgorithmButtonGroup.Title = 'Text Detection Algorithm';
            app.PrewittEdgeDetectionButton = uiradiobutton(app.TextDetectionAlgorithmButtonGroup);
            app.PrewittEdgeDetectionButton.Text = 'Prewitt Edge Detection';
            app.PrewittEdgeDetectionButton.Value = true;
            app.OtsusMethodButton = uiradiobutton(app.TextDetectionAlgorithmButtonGroup);
            app.OtsusMethodButton.Text = 'Otsu''s Method';
            app.ColorSelectButton = uiradiobutton(app.TextDetectionAlgorithmButtonGroup);
            app.ColorSelectButton.Text = 'Color-Select';
            app.UserGeneratedMaskButton = uiradiobutton(app.TextDetectionAlgorithmButtonGroup);
            app.UserGeneratedMaskButton.Text = 'User Generated Mask';
            app.RemoveTextButton = uibutton(app.UIFigure, 'push');
            app.RemoveTextButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTextButtonPushed, true);
            app.RemoveTextButton.Text = 'Remove Text';
            app.ImageFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.ImageFilenameEditFieldLabel.Text = 'Image Filename';
            app.ImageFilenameEditField = uieditfield(app.UIFigure, 'text');
            app.ImageFilenameEditField.ValueChangedFcn = createCallbackFcn(app, @ImageFilenameEditFieldValueChanged, true);
            app.ImageFilenameEditField.Value = '.jpg';
            app.RLabel = uilabel(app.UIFigure);
            app.RLabel.Text = 'R  ';
            app.RSpinner = uispinner(app.UIFigure);
            app.RSpinner.ValueChangedFcn = createCallbackFcn(app, @RSpinnerValueChanged, true);
            app.RSpinner.Limits = [0 255];
            app.RSpinner.ValueDisplayFormat = '%11.3g';
            app.RSpinner.Value = 255;
            app.GLabel = uilabel(app.UIFigure);
            app.GLabel.Text = 'G  ';
            app.GSpinner = uispinner(app.UIFigure);
            app.GSpinner.ValueChangedFcn = createCallbackFcn(app, @GSpinnerValueChanged, true);
            app.GSpinner.Limits = [0 255];
            app.GSpinner.ValueDisplayFormat = '%11.3g';
            app.GSpinner.Value = 123;
            app.BLabel = uilabel(app.UIFigure);
            app.BLabel.Text = 'B  ';
            app.BSpinner = uispinner(app.UIFigure);
            app.BSpinner.ValueChangedFcn = createCallbackFcn(app, @BSpinnerValueChanged, true);
            app.BSpinner.Limits = [0 255];
            app.BSpinner.ValueDisplayFormat = '%11.3g';
            app.DilationSpinnerLabel = uilabel(app.UIFigure);
            app.DilationSpinnerLabel.Text = 'Dilation  ';
            app.DilationSpinner = uispinner(app.UIFigure);
            app.DilationSpinner.ValueChangedFcn = createCallbackFcn(app, @DilationSpinnerValueChanged, true);
            app.DilationSpinner.Limits = [0 15];
            app.DilationSpinner.ValueDisplayFormat = '%11.3g';
            app.DilationSpinner.Value = 3;
            app.ECE590TextDetectionandInpaintingLabel = uilabel(app.UIFigure);
            app.ECE590TextDetectionandInpaintingLabel.Text = 'ECE 590 Text Detection and Inpainting ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end